classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Menu        matlab.ui.container.Menu
        Menu_3      matlab.ui.container.Menu
        CH1Menu     matlab.ui.container.Menu
        CH2Menu     matlab.ui.container.Menu
        Menu_4      matlab.ui.container.Menu
        Menu_5      matlab.ui.container.Menu
        Menu_6      matlab.ui.container.Menu
        Menu_2      matlab.ui.container.Menu
        Menu_7      matlab.ui.container.Menu
        Menu_9      matlab.ui.container.Menu
        CH1Menu_4   matlab.ui.container.Menu
        CH2Menu_4   matlab.ui.container.Menu
        MATLABMenu  matlab.ui.container.Menu
        CH1Menu_5   matlab.ui.container.Menu
        CH2Menu_5   matlab.ui.container.Menu
        Menu_8      matlab.ui.container.Menu
        Menu_12     matlab.ui.container.Menu
        Menu_13     matlab.ui.container.Menu
        Menu_14     matlab.ui.container.Menu
        FFTMenu     matlab.ui.container.Menu
        CH1Menu_6   matlab.ui.container.Menu
        CH2Menu_6   matlab.ui.container.Menu
        Menu_10     matlab.ui.container.Menu
        Menu_11     matlab.ui.container.Menu
        Spinner     matlab.ui.control.Spinner
        Label_3     matlab.ui.control.Label
        Label_2     matlab.ui.control.Label
        Label       matlab.ui.control.Label
        UIAxes2     matlab.ui.control.UIAxes
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %其他窗口的句柄
        generate_signal_app
        conv_app
        signal_synthesis_app
        signature_app
        
        %定时器
        Timer_record %用于录制信号
        Timer_genesig %用于产生信号
        
        %录制信号使用的私有变量
        H
        sampleRate = 44100
        timeLength = 0.05
        samples
        displayTime = 2
        displaySamples
        buffer
        timeArray
        pic

        maxbuffer%用于存储所有的录制数据
        analysis_flag%用于判断是否进行过录制
        kk=1%信号放大的增益值
        
    end
    
    properties (Access = public)
        %用于产生信号
        signal_type=1
        channel=1
        T=1
        %用于信号合成
        channell=1
        method=1
        %用于卷积
        N1=0.5
        N2=0.5
        N3=0
        channelll=1
        %用于信号特征
        f=0 %频率
        TT=0 %周期
        peak=0 %峰值
        p2p=0 %峰峰值
        mean=0 %平均值
        rmsd=0 %均方差
        var=0 %方差
        rms=0 %rms

    end
    
    methods (Access = private)
        
        function capture(app,~,~)%录制信号定时器的回调函数
            % 从音频设备采集数据
            audioIn = step(app.H)*10*app.kk;
            % 将数据放置在maxbuffer中
            app.maxbuffer=[app.maxbuffer,audioIn'];
            % 更新缓冲区：将新数据追加到缓冲区末尾，并移除旧数据
            app.buffer = [app.buffer(app.samples+1:end), audioIn'];
            
            % 更新振幅图
            app.pic.YData = app.buffer;
            
            % 刷新图形
            drawnow limitrate;  
        end

        function setupTimer_record(app,temp)%启动录制信号定时器


            % 如果定时器已存在，先停止并删除它
            if ~isempty(app.Timer_record) && isvalid(app.Timer_record)
                stop(app.Timer_record);
                delete(app.Timer_record);
            end
            
            % 如果音频设备读取对象存在，先释放它
            if isobject(app.H)
                release(app.H);
            end
            
            % 创建音频设备读取对象
            app.H = audioDeviceReader(...
                'DeviceName', '主声音捕获驱动程序', ...
                'NumChannels', 1, ...
                'BitDepth', '16-bit integer', ...
                'SamplesPerFrame', app.samples, ...
                'SampleRate', app.sampleRate);
            %将分析标志置为0
            app.analysis_flag=0;
            % 创建缓冲区
            app.buffer = zeros(1, app.displaySamples);
            app.timeArray = linspace(-app.displayTime, 0, app.displaySamples);
            if temp==1
                % 在UIAxes2上创建图形
                app.pic = plot(app.UIAxes, app.timeArray, zeros(1, app.displaySamples));
                set(app.UIAxes, 'xlim', [-app.displayTime 0], 'ylim', [-1 1], 'XTick', [], 'YTick', []);
            elseif temp==2
                app.pic = plot(app.UIAxes2, app.timeArray, zeros(1, app.displaySamples));
                set(app.UIAxes2, 'xlim', [-app.displayTime 0], 'ylim', [-1 1], 'XTick', [], 'YTick', []);
            end
            % 创建定时器对象
            app.Timer_record = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', app.timeLength, ...
                'TimerFcn', @(src, event) capture(app, src, event));
        end
    
        function axes_generate_signal(app)%产生信号定时器的回调函数
        end
        
        function setupTimer_genesig(app)%启动产生信号定时器
        end

    end
    
    methods (Access = public)
        
        function start_generate_signal()
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            app.Menu_5.Enable="off";
            app.generate_signal_app=generate_signal(app);
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            app.Label_2.Text = sprintf('Signal Type: %.1f, Channel: %.1f, T: %.1f, Channell: %.1f, method: %.1f, N1: %.1f, N2: %.1f, N3: %.1f, channelll: %.1f', app.signal_type, app.channel, app.T,app.channell,app.method,app.N1,app.N2,app.N3,app.channelll);
            app.analysis_flag=1;
            app.CH1Menu.Enable="on";
            app.CH2Menu.Enable="on";
            if ~isempty(app.Timer_record) && isvalid(app.Timer_record)
                stop(app.Timer_record);
                delete(app.Timer_record);
            end
        end

        % Menu selected function: Menu_8
        function Menu_8Selected(app, event)
            app.Menu_8.Enable="off";
            app.signal_synthesis_app=signal_synthesis(app);
        end

        % Menu selected function: Menu_11
        function Menu_11Selected(app, event)
            app.Menu_11.Enable="off";
            app.conv_app=conv(app);
        end

        % Menu selected function: CH1Menu_4
        function CH1Menu_4Selected(app, event)
            app.CH1Menu_4.Enable="off";
            app.CH1Menu_5.Enable="off";
            app.CH2Menu_4.Enable="off";
            app.CH2Menu_5.Enable="off";
            app.signature_app=signature(app);
        end

        % Menu selected function: CH2Menu_4
        function CH2Menu_4Selected(app, event)
            app.CH1Menu_4.Enable="off";
            app.CH1Menu_5.Enable="off";
            app.CH2Menu_4.Enable="off";
            app.CH2Menu_5.Enable="off";
            app.signature_app=signature(app);            
        end

        % Menu selected function: CH1Menu_5
        function CH1Menu_5Selected(app, event)
            app.CH1Menu_4.Enable="off";
            app.CH1Menu_5.Enable="off";
            app.CH2Menu_4.Enable="off";
            app.CH2Menu_5.Enable="off";
            app.signature_app=signature(app);            
        end

        % Menu selected function: CH2Menu_5
        function CH2Menu_5Selected(app, event)
            app.CH1Menu_4.Enable="off";
            app.CH1Menu_5.Enable="off";
            app.CH2Menu_4.Enable="off";
            app.CH2Menu_5.Enable="off";
            app.signature_app=signature(app);            
        end

        % Menu selected function: CH1Menu
        function CH1MenuSelected(app, event)
            % 初始化定时器参数
            app.samples = floor(app.timeLength * app.sampleRate);
            app.displaySamples = app.displayTime * app.sampleRate;
            
            % 设置定时器
            setupTimer_record(app,1);
            
            % 启动定时器
            start(app.Timer_record);
            %关闭按钮
            app.CH1Menu.Enable="off";
            app.CH2Menu.Enable="off";
        end

        % Menu selected function: CH2Menu
        function CH2MenuSelected(app, event)
            % 初始化定时器参数
            app.samples = floor(app.timeLength * app.sampleRate);
            app.displaySamples = app.displayTime * app.sampleRate;
            
            % 设置定时器
            setupTimer_record(app,2);
            
            % 启动定时器
            start(app.Timer_record);
            %关闭按钮
            app.CH1Menu.Enable="off";
            app.CH2Menu.Enable="off";
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.kk=changingValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '信号';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.Text = '开始录制信号';
            app.CH1Menu = uimenu(app.Menu_3);
            app.CH1Menu.MenuSelectedFcn = createCallbackFcn(app, @CH1MenuSelected, true);
            app.CH1Menu.Text = 'CH1';
            app.CH2Menu = uimenu(app.Menu_3);
            app.CH2Menu.MenuSelectedFcn = createCallbackFcn(app, @CH2MenuSelected, true);
            app.CH2Menu.Text = 'CH2';
            app.Menu_4 = uimenu(app.Menu);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '暂停录制信号';
            app.Menu_5 = uimenu(app.Menu);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '信号发生器';
            app.Menu_6 = uimenu(app.Menu);
            app.Menu_6.Text = '暂停发生信号';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '波形';
            app.Menu_7 = uimenu(app.Menu_2);
            app.Menu_7.Text = '波形特征';
            app.Menu_9 = uimenu(app.Menu_7);
            app.Menu_9.Text = '自定义函数';
            app.CH1Menu_4 = uimenu(app.Menu_9);
            app.CH1Menu_4.MenuSelectedFcn = createCallbackFcn(app, @CH1Menu_4Selected, true);
            app.CH1Menu_4.Text = 'CH1';
            app.CH2Menu_4 = uimenu(app.Menu_9);
            app.CH2Menu_4.MenuSelectedFcn = createCallbackFcn(app, @CH2Menu_4Selected, true);
            app.CH2Menu_4.Text = 'CH2';
            app.MATLABMenu = uimenu(app.Menu_7);
            app.MATLABMenu.Text = 'MATLAB函数';
            app.CH1Menu_5 = uimenu(app.MATLABMenu);
            app.CH1Menu_5.MenuSelectedFcn = createCallbackFcn(app, @CH1Menu_5Selected, true);
            app.CH1Menu_5.Text = 'CH1';
            app.CH2Menu_5 = uimenu(app.MATLABMenu);
            app.CH2Menu_5.MenuSelectedFcn = createCallbackFcn(app, @CH2Menu_5Selected, true);
            app.CH2Menu_5.Text = 'CH2';
            app.Menu_8 = uimenu(app.Menu_2);
            app.Menu_8.MenuSelectedFcn = createCallbackFcn(app, @Menu_8Selected, true);
            app.Menu_8.Text = '信号合成';
            app.Menu_12 = uimenu(app.UIFigure);
            app.Menu_12.Text = '高级计算';
            app.Menu_13 = uimenu(app.Menu_12);
            app.Menu_13.Text = '卷积';
            app.Menu_14 = uimenu(app.Menu_12);
            app.Menu_14.Text = '相关';
            app.FFTMenu = uimenu(app.UIFigure);
            app.FFTMenu.Text = 'FFT';
            app.CH1Menu_6 = uimenu(app.FFTMenu);
            app.CH1Menu_6.Text = 'CH1';
            app.CH2Menu_6 = uimenu(app.FFTMenu);
            app.CH2Menu_6.Text = 'CH2';
            app.Menu_10 = uimenu(app.UIFigure);
            app.Menu_10.Text = '滤波';
            app.Menu_11 = uimenu(app.Menu_10);
            app.Menu_11.MenuSelectedFcn = createCallbackFcn(app, @Menu_11Selected, true);
            app.Menu_11.Text = '卷积核';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'CH1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'CH2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '提示：';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '信号振幅放大';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end